classdef Space_display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PhosphorsDropDown          matlab.ui.control.DropDown
        PhosphorsDropDownLabel     matlab.ui.control.Label
        CFsDropDown                matlab.ui.control.DropDown
        CFsDropDownLabel           matlab.ui.control.Label
        DisplayspaceDropDown       matlab.ui.control.DropDown
        DisplayspaceDropDownLabel  matlab.ui.control.Label
        InputspaceDropDown         matlab.ui.control.DropDown
        InputspaceDropDownLabel    matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        ConvertButton              matlab.ui.control.Button
        fromImage                  matlab.ui.control.Image
        WhitePointDropDown         matlab.ui.control.DropDown
        WhitePointDropDownLabel    matlab.ui.control.Label
        SLMSpinner                 matlab.ui.control.Spinner
        SLMSpinner_2Label          matlab.ui.control.Label
        LMSpinner                  matlab.ui.control.Spinner
        LMSpinner_2Label           matlab.ui.control.Label
        LuminanceSpinner           matlab.ui.control.Spinner
        LuminanceSpinnerLabel      matlab.ui.control.Label
        fromAxes                   matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        BLabel                     matlab.ui.control.Label
        GLabel                     matlab.ui.control.Label
        RLabel                     matlab.ui.control.Label
        ToImage                    matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        %    load('illuminants.mat'); % Description
        si = struct() % standard illuminants
        phos
        cfs
        dkl
        rgb
        % startLMS
        %   startRGB
        background
        gamut
        spinRange
        fromImg
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd('F:\SO Backup\_Post-grad Research\cc'); %todo: fix, for preventing headaches now
            app.si = load('illuminants.mat');

            %Load all phosphors from directory
            phopath = dir(strcat(pwd, '\Recorded monitor phosphors\'));
            phosFiles = {phopath(3:end).name};
            app.PhosphorsDropDown.Items = phosFiles;
            app.PhosphorsDropDown.Value = app.PhosphorsDropDown.Items(1);
            app.phos = load(app.PhosphorsDropDown.Value);

            %Load all cone fundamentals from directory
            cfpath = dir(strcat(pwd, '\Fundamentals\'));
            cfsFiles = {cfpath(3:end).name}; %Remove . and ... Use regexp later?
            app.CFsDropDown.Items = cfsFiles;
            app.CFsDropDown.Value = app.CFsDropDown.Items(1);
            app.cfs = load(app.CFsDropDown.Value);

            % [app.startLMS] = spect2lms(app.phosphors.phosphors, app.cfs.cfs);
            % Get starting gamut
            app.gamut = findGamut(app.phos.phosphors, app.cfs.cfs, [0.5 0.5 0.5]);
            app.spinRange = minmax(app.gamut'); % Need to transpose because we want dkl

            app.background = spect2lms(app.phos.phosphors, app.cfs.cfs, [0.5 0.5 0.5]);


            app.ToImage.ImageSource = ones(100,100,3)/2;

            %             %todo remove, normalize
            %             app.LuminanceSpinner.Limits = app.spinRange(1,:);
            %             app.LMSpinner.Limits = app.spinRange(2,:);
            %             app.SLMSpinner.Limits = app.spinRange(3,:);
       

            % Constrain ranges, this is a kludge for now
            LumLimits = [-1 1];
            LMLimits = [-0.119, 0.106658033916124];
            SLMLimits = [-0.86 0.986];
            % Set step size
            numSteps = 100;

            app.LuminanceSpinner.Limits = LumLimits+.7;
            app.LMSpinner.Limits = LMLimits;
            app.SLMSpinner.Limits = SLMLimits;

            LumSteps = (LumLimits(2) - LumLimits(1)) / numSteps;
            LMSteps = (LMLimits(2) - LMLimits(1)) / numSteps;
            SLMSteps = (SLMLimits(2) - SLMLimits(1)) / numSteps;

            app.LuminanceSpinner.Step = LumSteps;
            app.LMSpinner.Step = LMSteps;
            app.SLMSpinner.Step = SLMSteps;
            app.fromImg = imread('DKL_space_blank.png');  
            imgSize = size(app.fromImg);

            middle = [imgSize(1)/2 imgSize(2)/2];
            xCenter = -middle(2) : imgSize(2) - middle(2);
            yCenter = -middle(1) : imgSize(1) - middle(1);
            image(app.fromImg, 'Parent', app.fromAxes, 'XData', xCenter, 'YData', yCenter);
            hold(app.fromAxes,'on')
            %plot(app.fromAxes, [10 20], [10 20], 'k')
            %rectangle(app.fromAxes, 'Position', [10 10 200 200],'FaceColor', [1 1 1])
            %app.fromAxes.XAxisLocation = 'origin';
            %    axis on
            %              ax = app.fromAxes;
            %
            % ax.YAxisLocation = 'origin';
          %  axis off
          %  axis equal
      %      plot(app.fromAxes, 100,100,'k')
        end

        % Value changed function: WhitePointDropDown
        function setWP(app, event)
            currWP = app.WhitePointDropDown.Value;
            eval(strcat('app.si.si.', currWP)) %FINALLY! Better way???
        end

        % Value changed function: LMSpinner, LuminanceSpinner, SLMSpinner
        function LMSValueChanged(app, event)

        end

        % Value changed function: PhosphorsDropDown
        function PhosphorsDropDownValueChanged(app, event)
            value = app.PhosphorsDropDown.Value;
            app.phos = load(value);
            app.phos.phosphors = interpolateRange(app.phos.phosphors);
            app.gamut = findGamut(app.phos.phosphors, app.cfs.cfs, [0.5 0.5 0.5]);
        end

        % Value changed function: CFsDropDown
        function CFsDropDownValueChanged(app, event)
            value = app.CFsDropDown.Value;
            app.cfs = load(value);
            app.cfs.cfs = interpolateRange(app.cfs.cfs);
            app.gamut = findGamut(app.phos.phosphors, app.cfs.cfs, [0.5 0.5 0.5]);

        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            % Get values for all 3 even though only 1 changed

            no = 1;
            if no
                % Normalized values from spinners
                lumNorm = app.LuminanceSpinner.Value;
                lmNorm = app.LMSpinner.Value;
                slmNorm = app.SLMSpinner.Value;
                spinNorm =[lumNorm; lmNorm; slmNorm];

                % Convert to usable range
                spinRealVals = fromNorm(spinNorm, app.spinRange);

                %             luminance = app.LuminanceSpinner.Value;
                %             lm = app.LMSpinner.Value;
                %             slm = app.SLMSpinner.Value;


                app.dkl = spinRealVals';
            end


            app.dkl = -[app.LuminanceSpinner.Value, app.LMSpinner.Value, app.SLMSpinner.Value];
            lms = dkl2lms(app.background, app.dkl');
            app.rgb = lms2rgb(lms, app.phos.phosphors, app.cfs.cfs);

            %             app.RLabel.Text = sprintf('R = %s', num2str(app.rgb(1)*255));
            %             app.GLabel.Text = sprintf('G = %s', num2str(app.rgb(2)*255));
            %             app.BLabel.Text = sprintf('B = %s', num2str(app.rgb(3)*255));

            nonDec = 1;
            if nonDec
                rgbMult = 255;
            else
                rgbMult = 1;
            end

            rval = round(app.rgb(1),2) .* rgbMult;
            gval = round(app.rgb(2),2) .* rgbMult;
            bval = round(app.rgb(3),2) .* rgbMult;

            app.RLabel.Text = sprintf('R = %s', num2str(rval));
            app.GLabel.Text = sprintf('G = %s', num2str(gval));
            app.BLabel.Text = sprintf('B = %s', num2str(bval));

            LumVal = app.LuminanceSpinner.Value;
            LMVal = app.LMSpinner.Value;
            SLMVal = app.SLMSpinner.Value;

            LumLimits = app.LuminanceSpinner.Limits;
            LMLimits = app.LMSpinner.Limits;
            SLMLimits = app.SLMSpinner.Limits;

            rescaleLum = LumVal - (LumLimits(2) - LumLimits(1)) * 2;
            rescaleLum = rescaleLum - rescaleLum(1) - 1;
            rescaleLM = LMVal * (1 / (LMLimits(2) - LMLimits(1)));
            rescaleLM = (rescaleLM - rescaleLM(1)) * 2 - 1;
            rescaleSLM = SLMVal * (1 / (SLMLimits(2) - SLMLimits(1)));
            rescaleSLM = (rescaleSLM - rescaleSLM(1)) * 2 - 1;

            % Check for saturation
            [newRGB, flag] = checkSaturation(app.rgb);
            if flag
                flag = 0;
                app.rgb = newRGB;
                beep
            end

            %Generate RGB bitmap. Set as double for now, could try unit8
            toImg = cat(3, ones(100,100, 'double') * app.rgb(1), ...
                ones(100,100,'double') * app.rgb(2), ...
                ones(100,100,'double') * app.rgb(3));

            %Check for errors

            %Change RGB image
            app.ToImage.ImageSource = toImg;

            % Draw on original DKL image
            imgSize = size(app.fromImage);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {529, 529, 529};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {529, 529};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 323};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputspaceDropDownLabel = uilabel(app.LeftPanel);
            app.InputspaceDropDownLabel.Text = 'Input space';
            app.InputspaceDropDown = uidropdown(app.LeftPanel);
            app.InputspaceDropDown.Items = {'DKL'};
            app.InputspaceDropDown.Value = 'DKL';
            app.DisplayspaceDropDownLabel = uilabel(app.LeftPanel);
            app.DisplayspaceDropDownLabel.Text = 'Display space';
            app.DisplayspaceDropDown = uidropdown(app.LeftPanel);
            app.DisplayspaceDropDown.Items = {'RGB', 'MacLeod-Boynton', 'LMS', 'CIE1931 xyY'};
            app.DisplayspaceDropDown.Value = 'RGB';
            app.CFsDropDownLabel = uilabel(app.LeftPanel);
            app.CFsDropDownLabel.Text = 'CFs';
            app.CFsDropDown = uidropdown(app.LeftPanel);
            app.CFsDropDown.Items = {'', ''};
            app.CFsDropDown.ValueChangedFcn = createCallbackFcn(app, @CFsDropDownValueChanged, true);
            app.CFsDropDown.Value = '';
            app.PhosphorsDropDownLabel = uilabel(app.LeftPanel);
            app.PhosphorsDropDownLabel.Text = 'Phosphors';
            app.PhosphorsDropDown = uidropdown(app.LeftPanel);
            app.PhosphorsDropDown.Items = {};
            app.PhosphorsDropDown.ValueChangedFcn = createCallbackFcn(app, @PhosphorsDropDownValueChanged, true);
            app.PhosphorsDropDown.Value = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.fromAxes = uiaxes(app.CenterPanel);
            title(app.fromAxes, 'Title')
            xlabel(app.fromAxes, 'X')
            ylabel(app.fromAxes, 'Y')
            zlabel(app.fromAxes, 'Z')
            app.LuminanceSpinnerLabel = uilabel(app.CenterPanel);
            app.LuminanceSpinnerLabel.Text = 'Luminance';
            app.LuminanceSpinner = uispinner(app.CenterPanel);
            app.LuminanceSpinner.Step = 0.01;
            app.LuminanceSpinner.ValueChangedFcn = createCallbackFcn(app, @LMSValueChanged, true);
            app.LMSpinner_2Label = uilabel(app.CenterPanel);
            app.LMSpinner_2Label.Text = 'L - M';
            app.LMSpinner = uispinner(app.CenterPanel);
            app.LMSpinner.Step = 0.01;
            app.LMSpinner.ValueChangedFcn = createCallbackFcn(app, @LMSValueChanged, true);
            app.SLMSpinner_2Label = uilabel(app.CenterPanel);
            app.SLMSpinner_2Label.Text = 'S - (L + M)';
            app.SLMSpinner = uispinner(app.CenterPanel);
            app.SLMSpinner.Step = 0.01;
            app.SLMSpinner.ValueChangedFcn = createCallbackFcn(app, @LMSValueChanged, true);
            app.WhitePointDropDownLabel = uilabel(app.CenterPanel);
            app.WhitePointDropDownLabel.Text = 'White Point';
            app.WhitePointDropDown = uidropdown(app.CenterPanel);
            app.WhitePointDropDown.Items = {'a', 'c', 'd50', 'd55', 'd65', 'e', 'icc'};
            app.WhitePointDropDown.ValueChangedFcn = createCallbackFcn(app, @setWP, true);
            app.WhitePointDropDown.Value = 'a';
            app.fromImage = uiimage(app.CenterPanel);
            app.fromImage.ImageSource = 'DKL_space_blank.png';
            app.ConvertButton = uibutton(app.CenterPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert!';
            app.RightPanel = uipanel(app.GridLayout);
            app.ToImage = uiimage(app.RightPanel);
            app.RLabel = uilabel(app.RightPanel);
            app.RLabel.Text = 'R = ';
            app.GLabel = uilabel(app.RightPanel);
            app.GLabel.Text = 'G = ';
            app.BLabel = uilabel(app.RightPanel);
            app.BLabel.Text = 'B = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Space_display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end